classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout2                matlab.ui.container.GridLayout
        GUIPanel                   matlab.ui.container.Panel
        TopoLocalizationPanel      matlab.ui.container.Panel
        LocalizationSwitch_2       matlab.ui.control.Switch
        LocalizationSwitch_2Label  matlab.ui.control.Label
        GridLocalizationPanel      matlab.ui.container.Panel
        LocalizationSwitch         matlab.ui.control.Switch
        LocalizationSwitchLabel    matlab.ui.control.Label
        MatrixMapPanel             matlab.ui.container.Panel
        matrixMapGrid              matlab.ui.container.GridLayout
        Label_8                    matlab.ui.control.Label
        Label_7                    matlab.ui.control.Label
        Label_6                    matlab.ui.control.Label
        Label_5                    matlab.ui.control.Label
        Label_4                    matlab.ui.control.Label
        Label_3                    matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        XYLabel_2                  matlab.ui.control.Label
        RobotPositionPanel         matlab.ui.container.Panel
        GridLayout8                matlab.ui.container.GridLayout
        robotPositionY             matlab.ui.control.Label
        robotPositionX             matlab.ui.control.Label
        XYLabel                    matlab.ui.control.Label
        MatrixPathPlanPanel        matlab.ui.container.Panel
        GridLayout7                matlab.ui.container.GridLayout
        UITable                    matlab.ui.control.Table
        PlannedPathLabel           matlab.ui.control.Label
        PathStartButton            matlab.ui.control.Button
        goalPositionYEditField     matlab.ui.control.NumericEditField
        goalPositionXEditField     matlab.ui.control.NumericEditField
        GoalPositionXYLabel        matlab.ui.control.Label
        startPositionYEditField    matlab.ui.control.NumericEditField
        startPositionXEditField    matlab.ui.control.NumericEditField
        StartPositionXYLabel       matlab.ui.control.Label
        EnckderDataPanel           matlab.ui.container.Panel
        GridLayout6_2              matlab.ui.container.GridLayout
        rightEncoderData           matlab.ui.control.Label
        leftEncoderData            matlab.ui.control.Label
        RightEncoderLabel          matlab.ui.control.Label
        LeftEncoderLabel           matlab.ui.control.Label
        SonarDataPanel             matlab.ui.container.Panel
        GridLayout6                matlab.ui.container.GridLayout
        rightSonarData             matlab.ui.control.Label
        leftSonarData              matlab.ui.control.Label
        RightLabel_2               matlab.ui.control.Label
        LeftLabel_2                matlab.ui.control.Label
        LidarDataPanel             matlab.ui.container.Panel
        GridLayout5                matlab.ui.container.GridLayout
        rightLidarData             matlab.ui.control.Label
        leftLidarData              matlab.ui.control.Label
        backLidarData              matlab.ui.control.Label
        frontLidarData             matlab.ui.control.Label
        RightLabel                 matlab.ui.control.Label
        LeftLabel                  matlab.ui.control.Label
        BackLabel                  matlab.ui.control.Label
        FrontLabel                 matlab.ui.control.Label
        MQTTConnectionPanel        matlab.ui.container.Panel
        GridLayout4                matlab.ui.container.GridLayout
        ConnectStatusLabel         matlab.ui.control.Label
        mqttConnectSwitch          matlab.ui.control.Switch
        ServerPortEditField        matlab.ui.control.EditField
        ServerPortEditFieldLabel   matlab.ui.control.Label
        ServerUrlEditField         matlab.ui.control.EditField
        ServerUrlEditFieldLabel    matlab.ui.control.Label
        LEDStatusPanel             matlab.ui.container.Panel
        GridLayout                 matlab.ui.container.GridLayout
        YellowLedLabel             matlab.ui.control.Label
        GreenLedLabel              matlab.ui.control.Label
        RedLedLabel                matlab.ui.control.Label
        YellowLedLamp              matlab.ui.control.Lamp
        GreenLedLamp               matlab.ui.control.Lamp
        RedLedLamp                 matlab.ui.control.Lamp
    end


    properties (Access = private)
        walterRobot = "NULL";
        ledOffColor = [0.8 0.8 0.8];
        redLedOnColor = [1 0 0];
        greenLedOnColor = [0 1 0];
        yellowLedOnColor = [1 1 0];

        isPressingKey = false;
        
        matrixMapLabels = cell(4, 4);
    end

    methods (Access = private)

        function lidarDataCallback(app, topic, message)
            disp("Received from " + topic);
            disp("Message: " + message);
            if(topic==app.walterRobot.lidarDataTopic)
                app.walterRobot.lidarData = str2double(split(message));

                app.frontLidarData.Text = string(app.walterRobot.lidarData(1));
                app.backLidarData.Text = string(app.walterRobot.lidarData(2));
                app.leftLidarData.Text = string(app.walterRobot.lidarData(3));
                app.rightLidarData.Text = string(app.walterRobot.lidarData(4));


            end
        end

        function sonarDataCallback(app, topic, message)
            disp("Received from " + topic);
            disp("Message: " + message);
            if(topic==app.walterRobot.sonarDataTopic)
                app.walterRobot.sonarData = str2double(split(message));

                app.leftSonarData.Text = string(app.walterRobot.sonarData(1));
                app.rightSonarData.Text = string(app.walterRobot.sonarData(2));

            end
        end

        function encoderDataCallback(app, topic, message)
            disp("Received from " + topic);
            disp("Message: " + message);
            if(topic==app.walterRobot.encoderDataTopic)
                app.walterRobot.encoderData = str2double(split(message));

                app.leftEncoderData.Text = string(app.walterRobot.encoderData(1));
                app.rightEncoderData.Text = string(app.walterRobot.encoderData(2));

            end
        end

        function ledDataCallback(app, topic, message)
            disp("Received from " + topic);
            disp("Message: " + message);
            if(topic==app.walterRobot.ledDataTopic)
                app.walterRobot.ledData = split(message);

                % Red LED
                if app.walterRobot.ledData(1) == "0"
                    app.RedLedLamp.Color = app.ledOffColor;
                else
                    app.RedLedLamp.Color = app.redLedOnColor;
                end

                % Green LED
                if app.walterRobot.ledData(2) == "0"
                    app.GreenLedLamp.Color = app.ledOffColor;
                else
                    app.GreenLedLamp.Color = app.greenLedOnColor;
                end

                % Yellow LED
                if app.walterRobot.ledData(3) == "0"
                    app.YellowLedLamp.Color = app.ledOffColor;
                else
                    app.YellowLedLamp.Color = app.yellowLedOnColor;
                end

            end
        end

        function matrixDataCallback(app, topic, message)
            disp("Received from " + topic);
            disp("Message: " + message);
            
            matrixMessage = str2num(message);
            app.walterRobot.matrixMapData = matrixMessage;

            for x=1:4
                for y=1:4
                    app.matrixMapLabels{x, y}.Text = matrixMessage(x, y)+"";
                end
            end
        end

        function robotPositionCallback(app, topic, message)
            disp("Received from " + topic);
            disp("Message: " + message);

            robotPosition = str2num(message);
            app.walterRobot.robotPosition = robotPosition;
            if robotPosition(1) == -1 || robotPosition(2) == -1
                app.robotPositionX.Text = "unknown";
                app.robotPositionY.Text = "unknown";
            else
                app.robotPositionX.Text = robotPosition(1)+"";
                app.robotPositionY.Text = robotPosition(2)+"";
                
                % label the position on the map
                positionLabel = app.matrixMapLabels{robotPosition(1)+1, robotPosition(2)+1};
                positionLabel.FontColor = [0 1 0];
            end
        end

        function robotPossiblePositionCallback(app, topic, message)
            disp("Received from " + topic);
            disp("Message: " + message);
            
            possiblePositions = str2num(message);
            possiblePositionLength = size(possiblePositions);
            
            for x=1:4
                for y=1:4
                    app.matrixMapLabels{x, y}.FontColor = [0 0 0];
                end
            end

            for index=1:possiblePositionLength(1)
                app.matrixMapLabels{possiblePositions(index, 1)+1, possiblePositions(index, 2)+1}.FontColor = [1 0 0];
            end
        end

        function robotMatrixPathPlan(app, topic, message)
            disp("Received from " + topic);
            disp("Message: " + message);

            app.UITable.Data = str2num(message);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            for x = 2:5
                for y = 2:5
                    matrixCelllabel = uilabel(app.matrixMapGrid);
                    matrixCelllabel.HorizontalAlignment = 'center';
                    matrixCelllabel.FontSize = 24;
                    matrixCelllabel.Layout.Row = x;
                    matrixCelllabel.Layout.Column = y;
                    matrixCelllabel.Text = 'unknown';
                    app.matrixMapLabels{x-1, y-1} = matrixCelllabel;
                end
            end
        end

        % Value changed function: mqttConnectSwitch
        function mqttConnectSwitchValueChanged(app, event)
            value = app.mqttConnectSwitch.Value;

            if (value == "Connect")
                app.walterRobot = WalterRobot();
                disp('Connecting to MQTT Server...');
                app.walterRobot.mqClient = mqttclient(app.ServerUrlEditField.Value, Port=str2num(app.ServerPortEditField.Value));

                % add subscription topics
                app.walterRobot.mqClient.subscribe(app.walterRobot.lidarDataTopic, "Callback", @app.lidarDataCallback);
                app.walterRobot.mqClient.subscribe(app.walterRobot.sonarDataTopic, "Callback", @app.sonarDataCallback);
                app.walterRobot.mqClient.subscribe(app.walterRobot.encoderDataTopic, "Callback", @app.encoderDataCallback);
                app.walterRobot.mqClient.subscribe(app.walterRobot.ledDataTopic, "Callback", @app.ledDataCallback);
                app.walterRobot.mqClient.subscribe(app.walterRobot.matrixDataTopic, "Callback", @app.matrixDataCallback);
                app.walterRobot.mqClient.subscribe(app.walterRobot.robotPositionTopic , "Callback", @app.robotPositionCallback);
                app.walterRobot.mqClient.subscribe(app.walterRobot.robotPathPlanTopic , "Callback", @app.robotMatrixPathPlan);
                app.walterRobot.mqClient.subscribe(app.walterRobot.gridLocalizationResponseTopic , "Callback", @app.robotPossiblePositionCallback);
            else
                app.walterRobot = "NULL";
            end

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            if(~app.isPressingKey)
                switch event.Key
                    case 'w'
                        % disp("前进");
                        app.walterRobot.sendForwardControl();
                    case 's'
                        % disp("后退");
                        app.walterRobot.sendBackwardControl();
                    case 'a'
                        % disp("左转");
                        app.walterRobot.sendTurnLeftControl();
                    case 'd'
                        % disp("右转");
                        app.walterRobot.sendTurnRightControl();

                    % otherwise
                    %     disp("其他按键: " + event.Key);
                end

                app.isPressingKey = true;
            end
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)

            % 判断具体按键
            switch event.Key
                case 'w'
                    % disp("停止前进");
                    app.walterRobot.sendMoveStopControl();
                case 's'
                    % disp("停止后退");
                    app.walterRobot.sendMoveStopControl();
                case 'a'
                    % disp("停止左转");
                    app.walterRobot.sendMoveStopControl();
                case 'd'
                    % disp("停止右转");
                    app.walterRobot.sendMoveStopControl();
            end
            app.isPressingKey = false;
        end

        % Button pushed function: PathStartButton
        function PathStartButtonPushed(app, event)
            positions = sprintf('%d %d %d %d', int16(app.startPositionXEditField.Value), int16(app.startPositionYEditField.Value), int16(app.goalPositionXEditField.Value), int16(app.goalPositionYEditField.Value));
            app.walterRobot.sendPathPlanPosition(positions);
        end

        % Value changed function: LocalizationSwitch
        function LocalizationSwitchValueChanged(app, event)
            value = app.LocalizationSwitch.Value;
            if (value == "On")
                app.walterRobot.sendLocalizationCommand(app.walterRobot.startLocalization);
            else
                app.walterRobot.sendLocalizationCommand(app.walterRobot.endLocalization);
            end
            
        end

        % Value changed function: LocalizationSwitch_2
        function LocalizationSwitch_2ValueChanged(app, event)
            value = app.LocalizationSwitch_2.Value;
            if (value == "On")
                app.walterRobot.sendTopoLocalizationCommand(app.walterRobot.startLocalization);
            else
                app.walterRobot.sendTopoLocalizationCommand(app.walterRobot.endLocalization);
            end
        end

        % Callback function: not associated with a component
        function LocalizationSwitch_2ValueChanged2(app, event)
            value = app.LocalizationSwitch_2.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.GridLayout2 = uigridlayout(app.UIFigure);
            app.GUIPanel = uipanel(app.GridLayout2);
            app.GUIPanel.Title = 'GUI Panel';
            app.LEDStatusPanel = uipanel(app.GUIPanel);
            app.LEDStatusPanel.Title = 'LED Status';
            app.GridLayout = uigridlayout(app.LEDStatusPanel);
            app.RedLedLamp = uilamp(app.GridLayout);
            app.GreenLedLamp = uilamp(app.GridLayout);
            app.YellowLedLamp = uilamp(app.GridLayout);
            app.RedLedLabel = uilabel(app.GridLayout);
            app.RedLedLabel.Text = 'Red Led';
            app.GreenLedLabel = uilabel(app.GridLayout);
            app.GreenLedLabel.Text = 'Green Led';
            app.YellowLedLabel = uilabel(app.GridLayout);
            app.YellowLedLabel.Text = 'Yellow Led';
            app.MQTTConnectionPanel = uipanel(app.GUIPanel);
            app.MQTTConnectionPanel.Title = 'MQTT Connection';
            app.GridLayout4 = uigridlayout(app.MQTTConnectionPanel);
            app.ServerUrlEditFieldLabel = uilabel(app.GridLayout4);
            app.ServerUrlEditFieldLabel.Text = 'Server Url';
            app.ServerUrlEditField = uieditfield(app.GridLayout4, 'text');
            app.ServerUrlEditField.Value = 'mqtt://test.mosquitto.org';
            app.ServerPortEditFieldLabel = uilabel(app.GridLayout4);
            app.ServerPortEditFieldLabel.Text = 'Server Port';
            app.ServerPortEditField = uieditfield(app.GridLayout4, 'text');
            app.ServerPortEditField.InputType = 'digits';
            app.ServerPortEditField.Value = '1883';
            app.mqttConnectSwitch = uiswitch(app.GridLayout4, 'slider');
            app.mqttConnectSwitch.Items = {'Disconnect', 'Connect'};
            app.mqttConnectSwitch.ValueChangedFcn = createCallbackFcn(app, @mqttConnectSwitchValueChanged, true);
            app.mqttConnectSwitch.Value = 'Disconnect';
            app.ConnectStatusLabel = uilabel(app.GridLayout4);
            app.ConnectStatusLabel.Text = 'Connect Status';
            app.LidarDataPanel = uipanel(app.GUIPanel);
            app.LidarDataPanel.Title = 'Lidar Data';
            app.GridLayout5 = uigridlayout(app.LidarDataPanel);
            app.FrontLabel = uilabel(app.GridLayout5);
            app.FrontLabel.Text = 'Front:';
            app.BackLabel = uilabel(app.GridLayout5);
            app.BackLabel.Text = 'Back:';
            app.LeftLabel = uilabel(app.GridLayout5);
            app.LeftLabel.Text = 'Left:';
            app.RightLabel = uilabel(app.GridLayout5);
            app.RightLabel.Text = 'Right:';
            app.frontLidarData = uilabel(app.GridLayout5);
            app.frontLidarData.Text = 'Unknown';
            app.backLidarData = uilabel(app.GridLayout5);
            app.backLidarData.Text = 'Unknown';
            app.leftLidarData = uilabel(app.GridLayout5);
            app.leftLidarData.Text = 'Unknown';
            app.rightLidarData = uilabel(app.GridLayout5);
            app.rightLidarData.Text = 'Unknown';
            app.SonarDataPanel = uipanel(app.GUIPanel);
            app.SonarDataPanel.Title = 'Sonar Data';
            app.GridLayout6 = uigridlayout(app.SonarDataPanel);
            app.LeftLabel_2 = uilabel(app.GridLayout6);
            app.LeftLabel_2.Text = 'Left:';
            app.RightLabel_2 = uilabel(app.GridLayout6);
            app.RightLabel_2.Text = 'Right:';
            app.leftSonarData = uilabel(app.GridLayout6);
            app.leftSonarData.Text = 'Unknown';
            app.rightSonarData = uilabel(app.GridLayout6);
            app.rightSonarData.Text = 'Unknown';
            app.EnckderDataPanel = uipanel(app.GUIPanel);
            app.EnckderDataPanel.Title = 'Encoder Data';
            app.GridLayout6_2 = uigridlayout(app.EnckderDataPanel);
            app.LeftEncoderLabel = uilabel(app.GridLayout6_2);
            app.LeftEncoderLabel.Text = 'Left:';
            app.RightEncoderLabel = uilabel(app.GridLayout6_2);
            app.RightEncoderLabel.Text = 'Right:';
            app.leftEncoderData = uilabel(app.GridLayout6_2);
            app.leftEncoderData.Text = 'Unknown';
            app.rightEncoderData = uilabel(app.GridLayout6_2);
            app.rightEncoderData.Text = 'Unknown';
            app.MatrixPathPlanPanel = uipanel(app.GUIPanel);
            app.MatrixPathPlanPanel.Title = 'Matrix Path Plan';
            app.GridLayout7 = uigridlayout(app.MatrixPathPlanPanel);
            app.StartPositionXYLabel = uilabel(app.GridLayout7);
            app.StartPositionXYLabel.Text = 'Start Position (X, Y):';
            app.startPositionXEditField = uieditfield(app.GridLayout7, 'numeric');
            app.startPositionXEditField.Limits = [0 3];
            app.startPositionYEditField = uieditfield(app.GridLayout7, 'numeric');
            app.startPositionYEditField.Limits = [0 3];
            app.GoalPositionXYLabel = uilabel(app.GridLayout7);
            app.GoalPositionXYLabel.Text = 'Goal Position (X, Y):';
            app.goalPositionXEditField = uieditfield(app.GridLayout7, 'numeric');
            app.goalPositionXEditField.Limits = [0 3];
            app.goalPositionYEditField = uieditfield(app.GridLayout7, 'numeric');
            app.goalPositionYEditField.Limits = [0 3];
            app.goalPositionYEditField.ValueDisplayFormat = '%.0f';
            app.PathStartButton = uibutton(app.GridLayout7, 'push');
            app.PathStartButton.ButtonPushedFcn = createCallbackFcn(app, @PathStartButtonPushed, true);
            app.PathStartButton.Text = 'Path Start';
            app.PlannedPathLabel = uilabel(app.GridLayout7);
            app.PlannedPathLabel.Text = 'Planned Path:';
            app.UITable = uitable(app.GridLayout7);
            app.UITable.ColumnName = {'X'; 'Y'};
            app.UITable.RowName = {};
            app.RobotPositionPanel = uipanel(app.GUIPanel);
            app.RobotPositionPanel.Title = 'Robot Position';
            app.GridLayout8 = uigridlayout(app.RobotPositionPanel);
            app.XYLabel = uilabel(app.GridLayout8);
            app.XYLabel.Text = '(X, Y): ';
            app.robotPositionX = uilabel(app.GridLayout8);
            app.robotPositionX.Text = 'unknown';
            app.robotPositionY = uilabel(app.GridLayout8);
            app.robotPositionY.Text = 'unknown';
            app.MatrixMapPanel = uipanel(app.GUIPanel);
            app.MatrixMapPanel.Title = 'Matrix Map';
            app.matrixMapGrid = uigridlayout(app.MatrixMapPanel);
            app.XYLabel_2 = uilabel(app.matrixMapGrid);
            app.XYLabel_2.Text = 'X / Y';
            app.Label = uilabel(app.matrixMapGrid);
            app.Label.Text = '0';
            app.Label_2 = uilabel(app.matrixMapGrid);
            app.Label_2.Text = '1';
            app.Label_3 = uilabel(app.matrixMapGrid);
            app.Label_3.Text = '2';
            app.Label_4 = uilabel(app.matrixMapGrid);
            app.Label_4.Text = '3';
            app.Label_5 = uilabel(app.matrixMapGrid);
            app.Label_5.Text = '0';
            app.Label_6 = uilabel(app.matrixMapGrid);
            app.Label_6.Text = '1';
            app.Label_7 = uilabel(app.matrixMapGrid);
            app.Label_7.Text = '2';
            app.Label_8 = uilabel(app.matrixMapGrid);
            app.Label_8.Text = '3';
            app.GridLocalizationPanel = uipanel(app.GUIPanel);
            app.GridLocalizationPanel.Title = 'Grid Localization';
            app.LocalizationSwitchLabel = uilabel(app.GridLocalizationPanel);
            app.LocalizationSwitchLabel.Text = 'Localization';
            app.LocalizationSwitch = uiswitch(app.GridLocalizationPanel, 'slider');
            app.LocalizationSwitch.ValueChangedFcn = createCallbackFcn(app, @LocalizationSwitchValueChanged, true);
            app.TopoLocalizationPanel = uipanel(app.GUIPanel);
            app.TopoLocalizationPanel.Title = 'Topo Localization';
            app.LocalizationSwitch_2Label = uilabel(app.TopoLocalizationPanel);
            app.LocalizationSwitch_2Label.Text = 'Localization';
            app.LocalizationSwitch_2 = uiswitch(app.TopoLocalizationPanel, 'slider');
            app.LocalizationSwitch_2.ValueChangedFcn = createCallbackFcn(app, @LocalizationSwitch_2ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end